classdef my_gui_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        TwodimensionalarrayonyzplaneTab  matlab.ui.container.Tab
        EnteryourinputsPanel_2         matlab.ui.container.Panel
        DistancebetweenantennasinzdirEditFieldLabel  matlab.ui.control.Label
        DistancebetweenantennasinzdirEditField  matlab.ui.control.NumericEditField
        NumberofantennasinzdirectionEditFieldLabel  matlab.ui.control.Label
        NumberofantennasinzdirectionEditField  matlab.ui.control.NumericEditField
        InterferenceangleofantennadegreesEditField_2Label  matlab.ui.control.Label
        InterferenceangleofantennadegreesEditField_2  matlab.ui.control.NumericEditField
        DesiredangleofantennadegreesEditField_2Label  matlab.ui.control.Label
        DesiredangleofantennadegreesEditField_2  matlab.ui.control.NumericEditField
        DesiredLambdaEditField_2Label  matlab.ui.control.Label
        DesiredLambdaEditField_2       matlab.ui.control.NumericEditField
        DistancebetweenantennasinydirEditFieldLabel  matlab.ui.control.Label
        DistancebetweenantennasinydirEditField  matlab.ui.control.NumericEditField
        NumberofantennasinydirectionEditFieldLabel  matlab.ui.control.Label
        NumberofantennasinydirectionEditField  matlab.ui.control.NumericEditField
        ChooseoperationButtonGroup_2   matlab.ui.container.ButtonGroup
        AdaGradwithmachinelearningButton_2  matlab.ui.control.RadioButton
        LeastmeansquaresalgorithmButton_2  matlab.ui.control.RadioButton
        WeightsTextArea_3              matlab.ui.control.TextArea
        WeightsTextArea_3Label         matlab.ui.control.Label
        CalculateButton_2              matlab.ui.control.Button
        UIAxes2                        matlab.ui.control.UIAxes
        UniformLinearArrayTab          matlab.ui.container.Tab
        EnteryourinputsPanel           matlab.ui.container.Panel
        InterferenceangleofantennadegreesEditFieldLabel  matlab.ui.control.Label
        InterferenceangleofantennadegreesEditField  matlab.ui.control.NumericEditField
        DesiredangleofantennadegreesEditFieldLabel  matlab.ui.control.Label
        DesiredangleofantennadegreesEditField  matlab.ui.control.NumericEditField
        DesiredLambdaEditFieldLabel    matlab.ui.control.Label
        DesiredLambdaEditField         matlab.ui.control.NumericEditField
        DistancebetweenantennasEditFieldLabel  matlab.ui.control.Label
        DistancebetweenantennasEditField  matlab.ui.control.NumericEditField
        Numberofantennas120EditFieldLabel  matlab.ui.control.Label
        Numberofantennas120EditField   matlab.ui.control.NumericEditField
        CalculateButton                matlab.ui.control.Button
        WeightsTextArea                matlab.ui.control.TextArea
        WeightsTextAreaLabel           matlab.ui.control.Label
        ChooseoperationButtonGroup     matlab.ui.container.ButtonGroup
        ArtificialNeuralNetworkalgorithmButton  matlab.ui.control.RadioButton
        DeepLearningusingLSTMandRegressionButton  matlab.ui.control.RadioButton
        AdaGradwithmachinelearningButton  matlab.ui.control.RadioButton
        LeastmeansquaresalgorithmButton  matlab.ui.control.RadioButton
        UIAxes                         matlab.ui.control.UIAxes
        AdaptivebeamformingusingmachinelearningalgorithmsLabel  matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
                    if app.LeastmeansquaresalgorithmButton.Value
                        theta = -pi/2:.01:pi/2;
                        AF = zeros(1,length(theta));
                        w_lms = lms_func(app.Numberofantennas120EditField.Value,app.DistancebetweenantennasEditField.Value,app.DesiredLambdaEditField.Value,app.DesiredangleofantennadegreesEditField.Value,app.InterferenceangleofantennadegreesEditField.Value);
                        app.WeightsTextArea.Value = mat2str(w_lms);
            
                        for i = 1:app.Numberofantennas120EditField.Value
                            AF = AF + w_lms(i)'.*exp(1j*(i-1)*2*pi*app.DistancebetweenantennasEditField.Value*sin(theta));
                        end
                    
                        plot(app.UIAxes,theta*180/pi,abs(AF)/max(abs(AF)),'k');         
                    end 
                   if app.AdaGradwithmachinelearningButton.Value
                        theta = -pi/2:.01:pi/2;
                        AF = zeros(1,length(theta));
                        w_ada = adagrad(app.Numberofantennas120EditField.Value,app.DistancebetweenantennasEditField.Value,app.DesiredLambdaEditField.Value,app.DesiredangleofantennadegreesEditField.Value,app.InterferenceangleofantennadegreesEditField.Value);
                        app.WeightsTextArea.Value = mat2str(w_ada);
            
                        for i = 1:app.Numberofantennas120EditField.Value
                            AF = AF + w_ada(i)'.*exp(1j*(i-1)*2*pi*app.DistancebetweenantennasEditField.Value*sin(theta));
                        end
                        
                        plot(app.UIAxes,theta*180/pi,abs(AF)/max(abs(AF)),'k');
                   end
                   if app.DeepLearningusingLSTMandRegressionButton.Value
                        theta = -pi/2:.01:pi/2;
                        AF = zeros(1,length(theta));
                        w_deep = deep_func(app.Numberofantennas120EditField.Value,app.DistancebetweenantennasEditField.Value,app.DesiredLambdaEditField.Value,app.DesiredangleofantennadegreesEditField.Value,app.InterferenceangleofantennadegreesEditField.Value);
                        app.WeightsTextArea.Value = mat2str(w_deep);
            
                        for i = 1:app.Numberofantennas120EditField.Value
                            AF = AF + w_deep(i)'.*exp(1j*(i-1)*2*pi*app.DistancebetweenantennasEditField.Value*sin(theta));
                        end
                        
                        plot(app.UIAxes,theta*180/pi,abs(AF)/max(abs(AF)),'k');
                   end
                   if app.ArtificialNeuralNetworkalgorithmButton.Value
                        theta = -pi/2:.01:pi/2;
                        AF = zeros(1,length(theta));
                        w_ann = ann(app.Numberofantennas120EditField.Value,app.DistancebetweenantennasEditField.Value,app.DesiredLambdaEditField.Value,app.DesiredangleofantennadegreesEditField.Value,app.InterferenceangleofantennadegreesEditField.Value);
                        app.WeightsTextArea.Value = mat2str(w_ann);
            
                        for i = 1:app.Numberofantennas120EditField.Value
                            AF = AF + w_ann(i)'.*exp(1j*(i-1)*2*pi*app.DistancebetweenantennasEditField.Value*sin(theta));
                        end
                        
                        plot(app.UIAxes,theta*180/pi,abs(AF)/max(abs(AF)),'k');
                   end
        end

        % Value changed function: Numberofantennas120EditField
        function Numberofantennas120EditFieldValueChanged(app, event)
        
        end

        % Value changed function: DistancebetweenantennasEditField
        function DistancebetweenantennasEditFieldValueChanged(app, event)
      
        end

        % Value changed function: DesiredLambdaEditField
        function DesiredLambdaEditFieldValueChanged(app, event)

        end

        % Value changed function: 
        % DesiredangleofantennadegreesEditField
        function DesiredangleofantennadegreesEditFieldValueChanged(app, event)
            
        end

        % Value changed function: 
        % InterferenceangleofantennadegreesEditField
        function InterferenceangleofantennadegreesEditFieldValueChanged(app, event)

        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Button pushed function: CalculateButton_2
        function CalculateButton_2Pushed(app, event)
            if app.LeastmeansquaresalgorithmButton_2.Value
                    [weighty_lms,weightz_lms,AF_lms] = two_dim_array_lms(app.NumberofantennasinydirectionEditField.Value,app.NumberofantennasinzdirectionEditField.Value,app.DistancebetweenantennasinydirEditField.Value,app.DistancebetweenantennasinzdirEditField.Value,app.DesiredLambdaEditField_2.Value,app.DesiredangleofantennadegreesEditField_2.Value,app.InterferenceangleofantennadegreesEditField_2.Value);
                    app.WeightsTextArea_3.Value = "Weight of y antennas:" + mat2str(weighty_lms)+"Weight of z antennas:"+mat2str(weightz_lms);           
                    
                    M=361; %Angle resolution
                    theta=linspace(0,pi,M);
                    phi=linspace(-pi/2,pi/2,M);
                    [THETA,PHI]=meshgrid(theta,phi);
                    surf(app.UIAxes2,PHI,THETA,abs(AF_lms));
                    colormap(app.UIAxes2,'default');
                    set(surf(app.UIAxes2,PHI,THETA,abs(AF_lms)),'LineStyle','none');
                     
           end 
            if app.AdaGradwithmachinelearningButton_2.Value
                   [weighty_ada,weightz_ada,AF_ada] = two_dim_array_adagrad(app.NumberofantennasinydirectionEditField.Value,app.NumberofantennasinzdirectionEditField.Value,app.DistancebetweenantennasinydirEditField.Value,app.DistancebetweenantennasinzdirEditField.Value,app.DesiredLambdaEditField_2.Value,app.DesiredangleofantennadegreesEditField_2.Value,app.InterferenceangleofantennadegreesEditField_2.Value);
                   app.WeightsTextArea_3.Value = "Weight of y antennas:" + mat2str(weighty_ada)+"Weight of z antennas:"+mat2str(weightz_ada);           
                        
                   M=361; %Angle resolution
                   theta=linspace(0,pi,M);
                   phi=linspace(-pi/2,pi/2,M);
                   [THETA,PHI]=meshgrid(theta,phi);
                   surf(app.UIAxes2,PHI,THETA,abs(AF_ada));
                   colormap(app.UIAxes2,'default');
                   set(surf(app.UIAxes2,PHI,THETA,abs(AF_ada)),'LineStyle','none');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AdaptivebeamformingusingmachinelearningalgorithmsLabel = uilabel(app.UIFigure);
            app.AdaptivebeamformingusingmachinelearningalgorithmsLabel.Text = 'Adaptive beamforming using machine learning algorithms';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TwodimensionalarrayonyzplaneTab = uitab(app.TabGroup);
            app.TwodimensionalarrayonyzplaneTab.Title = 'Two dimensional array on yz plane';
            app.UIAxes2 = uiaxes(app.TwodimensionalarrayonyzplaneTab);
            title(app.UIAxes2, 'Plot of Array Factor')
            xlabel(app.UIAxes2, '\phi[deg]')
            ylabel(app.UIAxes2, '\Theta[deg]')
            zlabel(app.UIAxes2, 'Array Factor')
            app.UIAxes2.XTick = [-1.5707963267949 -1.0471975511966 -0.523598775598299 0 0.523598775598299 1.0471975511966 1.5707963267949];
            app.UIAxes2.XTickLabel = {'-90'; '-60'; '-30'; '0'; '30'; '60'; '90'};
            app.UIAxes2.YTick = [0 0.523598775598299 1.0471975511966 1.5707963267949 2.0943951023932 2.61799387799149 3.14159265358979];
            app.UIAxes2.YTickLabel = {'-90'; '-60'; '-30'; '0'; '30'; '60'; '90'};
            app.CalculateButton_2 = uibutton(app.TwodimensionalarrayonyzplaneTab, 'push');
            app.CalculateButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_2Pushed, true);
            app.CalculateButton_2.Text = 'Calculate';
            app.WeightsTextArea_3Label = uilabel(app.TwodimensionalarrayonyzplaneTab);
            app.WeightsTextArea_3Label.Text = 'Weights';
            app.WeightsTextArea_3 = uitextarea(app.TwodimensionalarrayonyzplaneTab);
            app.ChooseoperationButtonGroup_2 = uibuttongroup(app.TwodimensionalarrayonyzplaneTab);
            app.ChooseoperationButtonGroup_2.Title = 'Choose operation';
            app.LeastmeansquaresalgorithmButton_2 = uiradiobutton(app.ChooseoperationButtonGroup_2);
            app.LeastmeansquaresalgorithmButton_2.Text = 'Least-mean squares algorithm';
            app.LeastmeansquaresalgorithmButton_2.Value = true;
            app.AdaGradwithmachinelearningButton_2 = uiradiobutton(app.ChooseoperationButtonGroup_2);
            app.AdaGradwithmachinelearningButton_2.Text = 'AdaGrad with machine learning ';
            app.EnteryourinputsPanel_2 = uipanel(app.TwodimensionalarrayonyzplaneTab);
            app.EnteryourinputsPanel_2.Title = 'Enter your inputs';
            app.NumberofantennasinydirectionEditField = uieditfield(app.EnteryourinputsPanel_2, 'numeric');
            app.NumberofantennasinydirectionEditFieldLabel = uilabel(app.EnteryourinputsPanel_2);
            app.NumberofantennasinydirectionEditFieldLabel.Text = 'Number of antennas in y direction';
            app.DistancebetweenantennasinydirEditField = uieditfield(app.EnteryourinputsPanel_2, 'numeric');
            app.DistancebetweenantennasinydirEditFieldLabel = uilabel(app.EnteryourinputsPanel_2);
            app.DistancebetweenantennasinydirEditFieldLabel.Text = 'Distance between antennas in y dir.';
            app.DesiredLambdaEditField_2 = uieditfield(app.EnteryourinputsPanel_2, 'numeric');
            app.DesiredLambdaEditField_2Label = uilabel(app.EnteryourinputsPanel_2);
            app.DesiredLambdaEditField_2Label.Text = 'Desired Lambda';
            app.DesiredangleofantennadegreesEditField_2 = uieditfield(app.EnteryourinputsPanel_2, 'numeric');
            app.DesiredangleofantennadegreesEditField_2Label = uilabel(app.EnteryourinputsPanel_2);
            app.DesiredangleofantennadegreesEditField_2Label.Text = 'Desired angle of antenna (degrees)';
            app.InterferenceangleofantennadegreesEditField_2 = uieditfield(app.EnteryourinputsPanel_2, 'numeric');
            app.InterferenceangleofantennadegreesEditField_2Label = uilabel(app.EnteryourinputsPanel_2);
            app.InterferenceangleofantennadegreesEditField_2Label.Text = 'Interference angle of antenna (degrees)';
            app.NumberofantennasinzdirectionEditField = uieditfield(app.EnteryourinputsPanel_2, 'numeric');
            app.NumberofantennasinzdirectionEditFieldLabel = uilabel(app.EnteryourinputsPanel_2);
            app.NumberofantennasinzdirectionEditFieldLabel.Text = 'Number of antennas in z direction';
            app.DistancebetweenantennasinzdirEditField = uieditfield(app.EnteryourinputsPanel_2, 'numeric');
            app.DistancebetweenantennasinzdirEditFieldLabel = uilabel(app.EnteryourinputsPanel_2);
            app.DistancebetweenantennasinzdirEditFieldLabel.Text = 'Distance between antennas in z dir.';
            app.UniformLinearArrayTab = uitab(app.TabGroup);
            app.UniformLinearArrayTab.Title = 'Uniform Linear Array';
            app.UIAxes = uiaxes(app.UniformLinearArrayTab);
            title(app.UIAxes, 'Plot of Array Factor')
            xlabel(app.UIAxes, 'AOA (deg)')
            ylabel(app.UIAxes, '|AF_n|')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.ChooseoperationButtonGroup = uibuttongroup(app.UniformLinearArrayTab);
            app.ChooseoperationButtonGroup.Title = 'Choose operation';
            app.LeastmeansquaresalgorithmButton = uiradiobutton(app.ChooseoperationButtonGroup);
            app.LeastmeansquaresalgorithmButton.Text = 'Least-mean squares algorithm';
            app.LeastmeansquaresalgorithmButton.Value = true;
            app.AdaGradwithmachinelearningButton = uiradiobutton(app.ChooseoperationButtonGroup);
            app.AdaGradwithmachinelearningButton.Text = 'AdaGrad with machine learning ';
            app.DeepLearningusingLSTMandRegressionButton = uiradiobutton(app.ChooseoperationButtonGroup);
            app.DeepLearningusingLSTMandRegressionButton.Text = 'Deep Learning using LSTM and Regression ';
            app.ArtificialNeuralNetworkalgorithmButton = uiradiobutton(app.ChooseoperationButtonGroup);
            app.ArtificialNeuralNetworkalgorithmButton.Text = 'Artificial Neural Network algorithm';
            app.WeightsTextAreaLabel = uilabel(app.UniformLinearArrayTab);
            app.WeightsTextAreaLabel.Text = 'Weights';
            app.WeightsTextArea = uitextarea(app.UniformLinearArrayTab);
            app.CalculateButton = uibutton(app.UniformLinearArrayTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.EnteryourinputsPanel = uipanel(app.UniformLinearArrayTab);
            app.EnteryourinputsPanel.Title = 'Enter your inputs';
            app.Numberofantennas120EditField = uieditfield(app.EnteryourinputsPanel, 'numeric');
            app.Numberofantennas120EditField.ValueChangedFcn = createCallbackFcn(app, @Numberofantennas120EditFieldValueChanged, true);
            app.Numberofantennas120EditFieldLabel = uilabel(app.EnteryourinputsPanel);
            app.Numberofantennas120EditFieldLabel.Text = 'Number of antennas (1-20)';
            app.DistancebetweenantennasEditField = uieditfield(app.EnteryourinputsPanel, 'numeric');
            app.DistancebetweenantennasEditField.ValueChangedFcn = createCallbackFcn(app, @DistancebetweenantennasEditFieldValueChanged, true);
            app.DistancebetweenantennasEditFieldLabel = uilabel(app.EnteryourinputsPanel);
            app.DistancebetweenantennasEditFieldLabel.Text = 'Distance between antennas';
            app.DesiredLambdaEditField = uieditfield(app.EnteryourinputsPanel, 'numeric');
            app.DesiredLambdaEditField.ValueChangedFcn = createCallbackFcn(app, @DesiredLambdaEditFieldValueChanged, true);
            app.DesiredLambdaEditFieldLabel = uilabel(app.EnteryourinputsPanel);
            app.DesiredLambdaEditFieldLabel.Text = 'Desired Lambda';
            app.DesiredangleofantennadegreesEditField = uieditfield(app.EnteryourinputsPanel, 'numeric');
            app.DesiredangleofantennadegreesEditField.ValueChangedFcn = createCallbackFcn(app, @DesiredangleofantennadegreesEditFieldValueChanged, true);
            app.DesiredangleofantennadegreesEditFieldLabel = uilabel(app.EnteryourinputsPanel);
            app.DesiredangleofantennadegreesEditFieldLabel.Text = 'Desired angle of antenna (degrees)';
            app.InterferenceangleofantennadegreesEditField = uieditfield(app.EnteryourinputsPanel, 'numeric');
            app.InterferenceangleofantennadegreesEditField.ValueChangedFcn = createCallbackFcn(app, @InterferenceangleofantennadegreesEditFieldValueChanged, true);
            app.InterferenceangleofantennadegreesEditFieldLabel = uilabel(app.EnteryourinputsPanel);
            app.InterferenceangleofantennadegreesEditFieldLabel.Text = 'Interference angle of antenna (degrees)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = my_gui_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end